classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSFAppUIFigure              matlab.ui.Figure
        PropagateButton             matlab.ui.control.Button
        VisualsListBox              matlab.ui.control.ListBox
        VisualsListBoxLabel         matlab.ui.control.Label
        PaddingRatioEditField       matlab.ui.control.NumericEditField
        PaddingRatioEditFieldLabel  matlab.ui.control.Label
        ResolutionEditFieldLabel_4  matlab.ui.control.Label
        WavelengthEditField         matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel    matlab.ui.control.Label
        DimensionsEditField         matlab.ui.control.NumericEditField
        DimensionsEditFieldLabel    matlab.ui.control.Label
        SizeEditField               matlab.ui.control.NumericEditField
        SizeEditFieldLabel          matlab.ui.control.Label
        ResolutionEditFieldLabel_3  matlab.ui.control.Label
        ResolutionEditFieldLabel_2  matlab.ui.control.Label
        ResolutionEditField         matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel    matlab.ui.control.Label
        AddVisualButton             matlab.ui.control.Button
        ElementsListBox_2           matlab.ui.control.ListBox
        AddElementButton            matlab.ui.control.Button
        ElementsListBox             matlab.ui.control.ListBox
        ElementsListBoxLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sim
    end
    
    methods (Access = private)
        
        function newElement = createElement(app, data)
            switch data.elementType
                case 'source'
                    disp('State is on');
                case 'lens'
                    disp('State is off');
                case 'detector'
                    disp('State is off');
                otherwise
                    error("Support for elementType '%s' has not yet been implemented.");
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center app window in screen.
            screenSize = get(0, 'ScreenSize');
            figWidth = app.OSFAppUIFigure.Position(3);
            figHeight = app.OSFAppUIFigure.Position(4);
            app.OSFAppUIFigure.Position(1) = (screenSize(3) - figWidth) / 2;
            app.OSFAppUIFigure.Position(2) = (screenSize(4) - figHeight) / 2;
            app.sim = osf.Sim(1e-6, 1e-3);

            % Create sim.
            app.sim.addPlane(0, name='Source');
            app.sim.addLens(5e-3, -10e-3, name='L1');
            app.sim.addLens(4e-3, 25e-3, name='L2');
            app.sim.addLens(2e-3, 10e-3, name='L3');
            app.sim.addLens(5e-3, 8e-3, name='L4');
            app.sim.addDetector(10e-3, [100 100], 5e-6, [1 1], name='Detector');
            osf.vis.attachData(app.UIAxes, osf.vis.prepData(app.sim, 'default'));
        end

        % Button pushed function: AddElementButton
        function AddElementButtonPushed(app, event)
            newElementData = osf.gui.ElementCreationApp;
            newElement = createElement(app, newElementData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OSFAppUIFigure = uifigure('Visible', 'off');
            app.OSFAppUIFigure.Name = 'OSF App';
            app.UIAxes = uiaxes(app.OSFAppUIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ElementsListBoxLabel = uilabel(app.OSFAppUIFigure);
            app.ElementsListBoxLabel.Text = 'Elements';
            app.ElementsListBox = uilistbox(app.OSFAppUIFigure);
            app.ElementsListBox.Items = {};
            app.ElementsListBox.Value = {};
            app.AddElementButton = uibutton(app.OSFAppUIFigure, 'push');
            app.AddElementButton.ButtonPushedFcn = createCallbackFcn(app, @AddElementButtonPushed, true);
            app.AddElementButton.Text = 'Add Element';
            app.ElementsListBox_2 = uilistbox(app.OSFAppUIFigure);
            app.ElementsListBox_2.Items = {'Item 1', ''};
            app.AddVisualButton = uibutton(app.OSFAppUIFigure, 'push');
            app.AddVisualButton.Text = 'Add Visual';
            app.ResolutionEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.ResolutionEditField.Value = 1;
            app.ResolutionEditFieldLabel_2 = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel_2.Text = 'μm';
            app.ResolutionEditFieldLabel_3 = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel_3.Text = 'mm';
            app.SizeEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.SizeEditField.Value = 1;
            app.DimensionsEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.DimensionsEditFieldLabel.Text = 'Dimensions';
            app.DimensionsEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.DimensionsEditField.Value = 2;
            app.WavelengthEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.WavelengthEditField.Value = 632;
            app.ResolutionEditFieldLabel_4 = uilabel(app.OSFAppUIFigure);
            app.ResolutionEditFieldLabel_4.Text = 'nm';
            app.PaddingRatioEditFieldLabel = uilabel(app.OSFAppUIFigure);
            app.PaddingRatioEditFieldLabel.Text = 'Padding Ratio';
            app.PaddingRatioEditField = uieditfield(app.OSFAppUIFigure, 'numeric');
            app.PaddingRatioEditField.Value = 2;
            app.VisualsListBoxLabel = uilabel(app.OSFAppUIFigure);
            app.VisualsListBoxLabel.Text = 'Visuals';
            app.VisualsListBox = uilistbox(app.OSFAppUIFigure);
            app.VisualsListBox.Items = {};
            app.VisualsListBox.Value = {};
            app.PropagateButton = uibutton(app.OSFAppUIFigure, 'push');
            app.PropagateButton.Text = 'Propagate';
            % Show the figure after all components are created
            app.OSFAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSFAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSFAppUIFigure)
        end
    end
end